classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GraphDisplyUIFigure  matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        UIAxes               matlab.ui.control.UIAxes
        UIAxes2              matlab.ui.control.UIAxes
        UIAxes3              matlab.ui.control.UIAxes
        UIAxes4              matlab.ui.control.UIAxes
        UIAxes5              matlab.ui.control.UIAxes
        UIAxes5_2            matlab.ui.control.UIAxes
        UIAxes5_3            matlab.ui.control.UIAxes
        UIAxes5_4            matlab.ui.control.UIAxes
        UIAxes5_5            matlab.ui.control.UIAxes
        UIAxes5_6            matlab.ui.control.UIAxes
        UIAxes5_7            matlab.ui.control.UIAxes
        UIAxes5_8            matlab.ui.control.UIAxes
        UIAxes5_9            matlab.ui.control.UIAxes
        UIAxes5_10           matlab.ui.control.UIAxes
        UITable              matlab.ui.control.Table
        UITable2             matlab.ui.control.Table
    end

    
    properties (Access = private)
        mainapp % 调用主app
        data
    end
    
    methods (Access = public)
        
        function Img = RGBimshow(app,img,color,plotSwitch)
            %%分割图像为25份，输出在坐标区，并取得分割后的图像矩阵Img,并编号
            %输入img为rgb格式图片，color为指定颜色模式，1r2g3b，123为灰度模式
            %plotSwitch为绘图开关，1绘制图像，0不绘制，提高运行速度
            switch color
                case 4
                    img1=rgb2gray(img);
                otherwise
                    img1=img(:,:,color);
            end
            
            %assignin('base','pp',img1); 
            
            [u1,v1]=size(img1);
            crop_u=floor(u1/5);%纵向分割值
            crop_v=floor(v1/5);%横向分割值
            
            %分割得到Img
            
            for j = 1:5
                for i = 1:5
                    rect{i+(j-1)*5}=[crop_v*(i-1) crop_u*(j-1) crop_v crop_u];
                    Img{i+(j-1)*5} =imcrop(img1,rect{i+(j-1)*5});
                end 
            end
            
            %assignin('base','pp',Img{13});   


            %%显示图像,依据plotSwitch切换
            if plotSwitch==1
               %画出分割线
               for j=1:4
                    img(:,crop_v*j:crop_v*j+5,:)=0;
               end           
               
               for i=1:4
                    img(crop_u*i:crop_u*i+5,:,:)=0;   
               end
                
                imshow(img,'Parent',app.UIAxes);
                %插入文本
                for i=1:5
                    for j=1:5
                        t=text(app.UIAxes,crop_v*(i-1)+15,crop_u*(j-1)+70,string(5*(j-1)+i));
                        t.Color=[103, 230, 220]./255;
                        t.FontSize=24;
                        t.FontWeight='bold';
                        t.FontName='Times';
                    end
                end
            else
            end
            
        end
        
        function [Signal,blockPromince]=BlockShow(app,block,lineNo,magThreshold,plotSwitch)
            %输入的block值为选择的分割区块的灰度图像
            %lineNo为取样线编号
            %magThreshold为梯度检测阈值
            %plotSwitch为绘图开关
           
            
            img.bwTeq=histeq(block);                                            %直方图均化提高对比度  
            
            
            [Gmag,Gdir]=imgradient(img.bwTeq,'sobel');                          %梯度检测，得到两个梯度矩阵，分别代表大小和方向      
            
            
            mag_threshold=max(Gmag)*magThreshold;                               %阈值大小控制，低于此数值认为为0
            Gmag(Gmag<mag_threshold)=0;         
            %%将角度转化到-90-90°
            Gdir(Gdir<0)=Gdir(Gdir<0)+180;
            Gdir=Gdir-90;
            dirdata=reshape(Gdir(Gmag~=0),[],1);
            
            %绘制直方图并控制属性,依据plotSwitch控制
            if plotSwitch==1
                h=histogram(app.UIAxes3,dirdata,180);                                 
                    h.FaceColor=[100 142 147]/255;
                    h.EdgeColor='none';
            else
                %不绘图直接计数
                [h.BinCounts,h.BinEdges]=histcounts(dirdata,180);
                %assignin('base','h',h);
            end
            
            %%图片主方向计数及其分布标准差计算
            app.data.maxIndex=find(h.BinCounts==max(h.BinCounts),1);    
            app.data.angle=90-0.5*(h.BinEdges(app.data.maxIndex)+h.BinEdges(app.data.maxIndex+1));
            app.data.angle=ceil(app.data.angle*100)/100;
            
            
            [h2.BinCounts,h2.BinEdges]=histcounts(dirdata,'Normalization','probability','BinMethod','scott');
            h3.BinCounts=[h.BinCounts(end),h.BinCounts,h.BinCounts(1)]; %防止无法检索边缘peaks
            
            app.data.angleSigma=std(h2.BinCounts*100);                                 %归一化scott方法求得其标准差，反映其分布均匀性
            [peak,~]=findpeaks(h3.BinCounts,'MinPeakProminence',mean(h.BinCounts),...  %两者乘积反映了条带方向的显著性与一致性
                'NPeaks',2,'SortStr','descend') ;                       %同时求其最大峰值与第二显著峰的比值，这反映其某方向梯度的显著性                                                                                                                    
            if length(peak)<2
                peak(2)=mean(h.BinCounts);
            end
            app.data.angleProminence=peak(1)/peak(2);
            
            blockPromince=app.data.angleSigma*app.data.angleProminence;
            
            if plotSwitch==1
                app.UITable2.Data=[90-app.data.angle,blockPromince];
            else
            end


            %assignin('base','ang',app.data.angle);
            
            
            %%旋转图片并显示
            img.bwRotate=imrotate(block,app.data.angle);                                  %图像旋转
            %%添加取样线
            [u2,~]=size(img.bwRotate);
            app.data.cut=floor(u2/10);
            
            for i=1:9
                Signal(i,:)=img.bwRotate(app.data.cut*i,:);                
                img.bwRotate(app.data.cut*i:app.data.cut*i+4,:)=255;               
            end
                        
            %%根据plotSwitch进行
            if plotSwitch==1
                imshow(img.bwRotate,'Parent',app.UIAxes2);                                %输出旋转后的图像
                %加入文本
                for i=1:9
                    t=text(app.UIAxes2,20,app.data.cut*i-14,string(i));
                        t.Color=[103, 230, 220]./255;
                        t.FontSize=12;
                        t.FontWeight='bold';
                        t.FontName='Times';
                end
            else
            end
           
            %绘制灰度信号
            pltSignal=Signal(lineNo,:);
            pltSignal=pltSignal(pltSignal~=0);
            
            if plotSwitch==1
                l=plot(app.UIAxes4,pltSignal);
                    l.Color=[100 142 147]/255;                
                    l.LineWidth=1;
            else
            end
            
        end


        function pitchPixel=emdFFT(app,signal,scalebarUm,scalebarPix,Interpolation,plotSwitch)
            %输出一个信号线的螺距值，pitchPixel为一个n*2矩阵，1列为螺距值，1列为可信度，并绘图
            % signal是信号值
            % fpThreshold是判断指纹区的阈值
            pitchPixel=[0,0];                %初始化
            
            lengthOrigin=length(signal);     %原始信号长度
            
            signal=signal(signal~=0);
            signal=double(signal);
            signal=signal-mean(signal,"all");
            
            lenthCut=length(signal);
            
            [IMF,residual]=emd(signal,'Interpolation',Interpolation);
            
            %fft计算
            N=size(signal,2);
            n=0:N-1;
            fs=1;
            f=n*fs/N; 
            fx=f(1:ceil(N/2));
            
            for i=1:size(IMF,2)
                app.data.fData=fft(IMF,N);
                app.data.fData=abs(app.data.fData(:,i))*2/N;
                app.data.ftData{i}=app.data.fData(1:ceil(N/2));
            end
            
            %残差的fft
            f_r=fft(residual,N);
            f_r=abs(f_r)*2/N;
            ft_r=f_r(1:ceil(N/2));
            
            %根据plotSwitch决定是否绘图
            if plotSwitch==1
                %绘图,EMD部分，只绘制IMF1-4.以及残差
                l(1)=plot(app.UIAxes5,IMF(:,1));
                l(2)=plot(app.UIAxes5_2,IMF(:,2));
                l(3)=plot(app.UIAxes5_3,IMF(:,3));            
                l(4)=plot(app.UIAxes5_4,residual);
                
                
                %绘图，fft部分
                l(5)=plot(app.UIAxes5_5,fx,app.data.ftData{1});
                l(6)=plot(app.UIAxes5_6,fx,app.data.ftData{2});
                l(7)=plot(app.UIAxes5_7,fx,app.data.ftData{3});
                l(8)=plot(app.UIAxes5_8,fx,ft_r);
                
                %补充IMF4
                l(9)=plot(app.UIAxes5_9,IMF(:,4));
                l(10)=plot(app.UIAxes5_10,fx,app.data.ftData{4});
                
                for i=1:10
                    l(i).Color=[100 142 147]/255;   
                    l(i).LineWidth=0.8;
                end
               
                app.UIAxes5_5.XLim=[0,0.25];
                app.UIAxes5_6.XLim=[0,0.25];
                app.UIAxes5_7.XLim=[0,0.25];
                app.UIAxes5_8.XLim=[0,0.25];
                app.UIAxes5_10.XLim=[0,0.25];
            else
            end
            
            %assignin('base','pp',IMF);
            %检测螺距
            peakAver=ones(1,3);
            if lengthOrigin/lenthCut<3
                %{
                for i=1:3        %根据显著性峰值大小判断主要周期信息
                    [peak,~]=findpeaks(IMF(:,i),'MinPeakProminence',0.1*max(IMF(:,i)));
                    peakAver(i)=mean(peak);                    
                end
                IMF_index=find(peakAver==max(peakAver),1);%选择的IMF编号
                %assignin('base','p',IMF_index);
                
                [FFT_peak,FFT_location]=findpeaks(app.data.ftData{IMF_index},'SortStr','descend','NPeaks',2);%FFT最大的两个峰
                %}
                
                overlayIMF=IMF(:,2)+IMF(:,3);
                overlayIMF_FT=fft(overlayIMF,N);
                overlayIMF_FT=abs(overlayIMF_FT)*2/N;
                overlayIMF_FFT=overlayIMF_FT(1:ceil(N/2));               
                [FFT_peak,FFT_location]=findpeaks(overlayIMF_FFT,'SortStr','descend','NPeaks',2);%FFT最大的两个峰
                
                
                FFT_frequency=fx(FFT_location);                             %对应频率
                
                pitchPixel(1)=1./FFT_frequency(1)*scalebarUm*2/scalebarPix;%螺距值
                pitchPixel(2)=FFT_peak(1)/FFT_peak(2);%半螺距置信度，为两个峰值高度差
                
                
            else
                pitchPixel(1)=0;
                pitchPixel(2)=0;
            end
            %%格式化，保留两位小数
            pitchPixel=round(pitchPixel,2);
            
            
            %%输出表格
            if plotSwitch==1
                app.UITable.Data=pitchPixel;  
            else
            end
            
            %pitchPixel=1;


        end
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.mainapp=mainapp;        %调用主app,由app2打开app1时，app2句柄赋给mainapp
            
            disableDefaultInteractivity(app.UIAxes);
            disableDefaultInteractivity(app.UIAxes2);
            disableDefaultInteractivity(app.UIAxes3);
            disableDefaultInteractivity(app.UIAxes4);
            disableDefaultInteractivity(app.UIAxes5);
            disableDefaultInteractivity(app.UIAxes5_2);
            disableDefaultInteractivity(app.UIAxes5_3);
            disableDefaultInteractivity(app.UIAxes5_4);
            disableDefaultInteractivity(app.UIAxes5_9);
            
        end

        % Close request function: GraphDisplyUIFigure
        function GraphDisplyUIFigureCloseRequest(app, event)
            %关闭时请求确认
            msg=strcat('请确认是否关闭程序，是否继续?');
            selection=uiconfirm(app.GraphDisplyUIFigure,msg,'Confirm Operation');
            if selection=="OK"
                delete(app.mainapp)
                delete(app)      
            else
                return
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GraphDisplyUIFigure = uifigure('Visible', 'off');
            app.GraphDisplyUIFigure.Name = 'GraphDisply';
            app.GraphDisplyUIFigure.CloseRequestFcn = createCallbackFcn(app, @GraphDisplyUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.GraphDisplyUIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Selected Image')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, 'Gradient Angular Histogram')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.UIAxes3.XLim = [-90 90];
            app.UIAxes4 = uiaxes(app.GridLayout);
            title(app.UIAxes4, 'Gray level Signal')
            xlabel(app.UIAxes4, '')
            ylabel(app.UIAxes4, '')
            app.UIAxes5 = uiaxes(app.GridLayout);
            title(app.UIAxes5, '')
            xlabel(app.UIAxes5, '')
            ylabel(app.UIAxes5, 'IMF1')
            app.UIAxes5.XTick = [];
            app.UIAxes5_2 = uiaxes(app.GridLayout);
            title(app.UIAxes5_2, '')
            xlabel(app.UIAxes5_2, '')
            ylabel(app.UIAxes5_2, 'IMF2')
            app.UIAxes5_2.XTick = [];
            app.UIAxes5_3 = uiaxes(app.GridLayout);
            title(app.UIAxes5_3, '')
            xlabel(app.UIAxes5_3, '')
            ylabel(app.UIAxes5_3, 'IMF3')
            app.UIAxes5_3.XTick = [];
            app.UIAxes5_4 = uiaxes(app.GridLayout);
            title(app.UIAxes5_4, '')
            xlabel(app.UIAxes5_4, '')
            ylabel(app.UIAxes5_4, 'Residual')
            app.UIAxes5_5 = uiaxes(app.GridLayout);
            title(app.UIAxes5_5, '')
            xlabel(app.UIAxes5_5, '')
            ylabel(app.UIAxes5_5, '')
            app.UIAxes5_5.XTick = [];
            app.UIAxes5_6 = uiaxes(app.GridLayout);
            title(app.UIAxes5_6, '')
            xlabel(app.UIAxes5_6, '')
            ylabel(app.UIAxes5_6, '')
            app.UIAxes5_6.XTick = [];
            app.UIAxes5_7 = uiaxes(app.GridLayout);
            title(app.UIAxes5_7, '')
            xlabel(app.UIAxes5_7, '')
            ylabel(app.UIAxes5_7, '')
            app.UIAxes5_7.XTick = [];
            app.UIAxes5_8 = uiaxes(app.GridLayout);
            title(app.UIAxes5_8, '')
            xlabel(app.UIAxes5_8, '')
            ylabel(app.UIAxes5_8, '')
            app.UIAxes5_9 = uiaxes(app.GridLayout);
            title(app.UIAxes5_9, '')
            xlabel(app.UIAxes5_9, '')
            ylabel(app.UIAxes5_9, 'IMF4')
            app.UIAxes5_9.XTick = [];
            app.UIAxes5_10 = uiaxes(app.GridLayout);
            title(app.UIAxes5_10, '')
            xlabel(app.UIAxes5_10, '')
            ylabel(app.UIAxes5_10, '')
            app.UIAxes5_10.XTick = [];
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Pitch(um)'; 'Prominence'};
            app.UITable.RowName = {};
            app.UITable.Tag = 'DataTable';
            app.UITable2 = uitable(app.GridLayout);
            app.UITable2.ColumnName = {'Gradient direction'; 'Prominence'};
            app.UITable2.RowName = {};
            % Show the figure after all components are created
            app.GraphDisplyUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GraphDisplyUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GraphDisplyUIFigure)
        end
    end
end