classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        paramentcontrolUIFigure      matlab.ui.Figure
        selectafolderButton          matlab.ui.control.Button
        imagelistListBoxLabel        matlab.ui.control.Label
        imagelistListBox             matlab.ui.control.ListBox
        scalebarumEditFieldLabel     matlab.ui.control.Label
        scalebarumEditField          matlab.ui.control.NumericEditField
        scalebarpixelEditFieldLabel  matlab.ui.control.Label
        scalebarpixelEditField       matlab.ui.control.NumericEditField
        TabGroup                     matlab.ui.container.TabGroup
        samplingTab                  matlab.ui.container.Tab
        blockNoLabel                 matlab.ui.control.Label
        blockNoEditField             matlab.ui.control.NumericEditField
        lineNoEditFieldLabel         matlab.ui.control.Label
        lineNoEditField              matlab.ui.control.NumericEditField
        controlTab                   matlab.ui.container.Tab
        gradientSpinnerLabel         matlab.ui.control.Label
        gradientSpinner              matlab.ui.control.Spinner
        interpolationDropDownLabel   matlab.ui.control.Label
        interpolationDropDown        matlab.ui.control.DropDown
        directionMinSpinnerLabel     matlab.ui.control.Label
        directionMinSpinner          matlab.ui.control.Spinner
        batchTab                     matlab.ui.container.Tab
        batchprocessingButton        matlab.ui.control.Button
        MailEditFieldLabel           matlab.ui.control.Label
        MailEditField                matlab.ui.control.EditField
        colormethodDropDown_2Label   matlab.ui.control.Label
        colormethodDropDown          matlab.ui.control.DropDown
        UITable                      matlab.ui.control.Table
    end

    
    properties (Access = private)
        chartapp % 调用图窗pp
        
        file    %文件相关变量
        data    %数据相关
        parameter   %参数相关
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.chartapp=app1(app);     %将app1的句柄赋给chartapp，app2为参数控制，app1为图片输出
            app.chartapp.GraphDisplyUIFigure.Visible='off';
            
            app.selectafolderButton.Enable='on';
        end

        % Button pushed function: selectafolderButton
        function selectafolderButtonPushed(app, event)
            
            app.UITable.Visible='off';
            %按下选择按钮，选择文件夹
            app.file.fname=uigetdir;
            app.file.foldername{1}=strcat(app.file.fname,'\');
            app.file.imglist=FileGet(app.file.foldername,'*.jpg');  %读取文件夹下全部.jpg文件
            
            %表格内容
            app.imagelistListBox.Items=erase(app.file.imglist,app.file.foldername{1});
            app.imagelistListBox.ItemsData=app.file.imglist;
            
            
            %assignin('base','pp',app.imagelistListBox);
            
            %app.colormethodDropDown.Enable='on';
            %控件关闭
            app.scalebarumEditField.Enable='off';
            app.scalebarpixelEditField.Enable='off';
            
            app.chartapp.GraphDisplyUIFigure.Visible='on';                  %显示app1
            %app.chartapp=app1(app);     %将app1的句柄赋给chartapp，app2为参数控制，app1为图片输出
            
            app.imagelistListBox.Enable='on';
        end

        % Value changed function: imagelistListBox
        function imagelistListBoxValueChanged(app, event)
            value = app.imagelistListBox.Value;
            %选择图片时，打开对应图片，调用app1中的public函数
            app.data.oriImg=imread(value);
            %得到分割后的图像矩阵
            
            app.data.rectImg=RGBimshow(app.chartapp,app.data.oriImg,str2double(app.colormethodDropDown.Value),1);
            
            %绘制线取样分割条，获取灰度信息
            [app.data.signal,app.data.blockPromince]=BlockShow(app.chartapp,app.data.rectImg{app.blockNoEditField.Value},app.lineNoEditField.Value,app.gradientSpinner.Value,1);
            
            app.data.pitchPixel=emdFFT(app.chartapp,app.data.signal(app.lineNoEditField.Value,:),...
                app.scalebarumEditField.Value,app.scalebarpixelEditField.Value,app.interpolationDropDown.Value,1);
           
            %控件启动
            
            app.colormethodDropDown.Enable='on';
            app.TabGroup.Visible='on';
        end

        % Value changed function: blockNoEditField, 
        % gradientSpinner, interpolationDropDown, lineNoEditField
        function gradientSpinnerValueChanged(app, event)
            %更改梯度阈值后重新绘图
            [app.data.signal,app.data.blockPromince]=BlockShow(app.chartapp,app.data.rectImg{app.blockNoEditField.Value},app.lineNoEditField.Value,app.gradientSpinner.Value,1);
            app.data.pitchPixel=emdFFT(app.chartapp,app.data.signal(app.lineNoEditField.Value,:),...
                app.scalebarumEditField.Value,app.scalebarpixelEditField.Value,app.interpolationDropDown.Value,1);
        end

        % Value changed function: colormethodDropDown
        function colormethodDropDownValueChanged(app, event)
            
            
            app.data.rectImg=RGBimshow(app.chartapp,app.data.oriImg,str2double(app.colormethodDropDown.Value),1);
            
            %绘制线取样分割条，获取灰度信息
            [app.data.signal,app.data.blockPromince]=BlockShow(app.chartapp,app.data.rectImg{app.blockNoEditField.Value},app.lineNoEditField.Value,app.gradientSpinner.Value,1);
            
            app.data.pitchPixel=emdFFT(app.chartapp,app.data.signal(app.lineNoEditField.Value,:),...
                app.scalebarumEditField.Value,app.scalebarpixelEditField.Value,app.interpolationDropDown.Value,1);
            
        end

        % Button pushed function: batchprocessingButton
        function batchprocessingButtonPushed(app, event)

            %%按下开始进行批处理
            selection='Cancel';
            msg=strcat('将以当前参数处理',app.file.fname,'下全部JPG图像，请确保参数符合要求，是否继续?');
            selection=uiconfirm(app.paramentcontrolUIFigure,msg,'Confirm Operation');
            
           try
                if selection=="OK"      %开始执行
                    dip=uiprogressdlg(app.paramentcontrolUIFigure,'Title','正在计算,请稍候', ...%创建进度条
                                                'Message',strcat('正在处理'),'Cancelable','on');
                                            
                    app.chartapp.GraphDisplyUIFigure.Visible='off';             %关闭app1的显示
                    
                    %{
                    %创建并行池p
                    ncore=feature('numCores');
                    if ncore>=3
                        p=parpool(ceil(ncore/2),'IdleTimeout',10);              %创建并行池
                    else
                        uialert(app.paramentcontrolUIFigure,'计算机配置过低无法并行计算','Warn');
                        return
                    end
                    %}
                    
                    imgNum=size(app.file.imglist,2);
                %====================================app广播变量转存===========================================%
                    color=str2double(app.colormethodDropDown.Value);
                    steps=imgNum*24;
                    pathout=app.file.foldername{1};%输出路径
                    [~, ~, ~]=mkdir(strcat(pathout,'\','Quantile-quantile'));%新建文件夹存放qq图
                    [~, ~, ~]=mkdir(strcat(pathout,'\','histogram'));%新建文件夹存放统计直方图
                    
                    %启动计时
                    t0=tic;
                    
                    for i=1:imgNum %此层次循环处理图片


                        oriImg=imread(app.file.imglist{i});
                        rectImg=RGBimshow(app.chartapp,oriImg,color,0);
                        
                    %================================内存预分配======================================================%    
                        fillLength=ceil(1.5*length(rectImg{1}));%预设signal信号长度，后续不足的用NaN补足
                       
                        allSignal=[];         %预分配，储存一个图片中全部信号，预设为[]，
                        blockPromince=ones(24); %预分配
                        allPitchPixel=zeros(24*9,2);%预分配，记录全部螺距和置信度数据
                        
                    %===============================此层次循环用于计算不同block下的信号===========================%
                    
                        for j=1:24     
                            if dip.CancelRequested
                                app.chartapp.GraphDisplyUIFigure.Visible='on';             %启动app1的显示
                                return
                            end
                            
                            %signal为一个9行矩阵，储存信号，blockpromience为block的可信度，用于判断此block是否为指纹区
                            [signal,blockPromince(j)]=BlockShow(app.chartapp,rectImg{j},1,app.gradientSpinner.Value,0);
                            N=NaN(9,fillLength-length(signal));
                            signal=double(signal);
                            signal=[signal,N];   %signal信号长度统一，不足用NaN补齐
                            %assignin('base','pp',signal);
                            if blockPromince(j)<app.directionMinSpinner.Value   %小于阈值的block视为无信号返回
                                signal=[];                           
                            end
                            
                            allSignal=[allSignal;signal];                   %串联信号,循环完毕后储存全部信号
     
                            
                            %==========================刷新进度条=================================%
                            step=((i-1)*24+j);
                            dip.Value=step/steps;
                            dip.Message=strcat('正在处理',app.file.imglist{i});
                                                                     
                        end
                    %========================================================================================%  
                    %==============================此层次循环处理allsignal得到一张图片的全部信息=====================================%
                        for ki=1:size(allSignal,1)
                            
                            singleSignal=allSignal(ki,:);
                            singleSignal=singleSignal(~isnan(singleSignal));%清除其中的nan值
                            %求取全部螺距数值，并输出到allPitchPixel内，单位已经是um，并排除0值
                            allPitchPixel(ki,:)=emdFFT(app.chartapp,singleSignal,app.scalebarumEditField.Value,app.scalebarpixelEditField.Value,app.interpolationDropDown.Value,0);
                            
                        end
                        
                        %保存求取的螺距原数据，后面使用
                        %assignin('base','pp',allPitchPixel);
                        allPitchPixel=allPitchPixel(allPitchPixel(:,1)~=0,:);
                        pitch2save(i).pitchAndPromience=allPitchPixel;
                        
                        
                       %===========================================================================================================%
                        %对一张图片内的全部螺距进行统计，采取广义极值分布
                       try
                            pd=fitdist(allPitchPixel(:,1),'gev');   %统计广义极值分布
                            %p95=paramci(pd);                       %95%置信区间
                            %p95=round((p95(:,1)-p95(:,2))*0.5,2);            %95%置信区间宽度
                            %PitchWidth(i)=p95(1);SigmaWidth(i)=p95(2);
                            
                            pitchRange=[min(allPitchPixel(:,1)):0.01:max(allPitchPixel(:,1))];
                            [~,pitchmax_index]=max(pdf(pd,pitchRange));
                            Pitch(i)=pitchRange(pitchmax_index);
                            
                            k(i)=pd.k;
                            m(i)=pd.mu;
                            s(i)=pd.sigma;   %k形状参数，m位置参数，s尺度参数                                          
                            
                            
                            %assignin('base','pp',allPitchPixel);
                            Promience(i)=round(mean(allPitchPixel(:,2)),2);        %显著性
                            
                            %==========绘制qq图表征拟合良度====================%
                            
                            f=figure('Visible','off');
                            h=qqplot(allPitchPixel(:,1),pd);             
                            qqName=strcat(pathout,'Quantile-quantile','\',erase(app.imagelistListBox.Items(i),'.jpg'),'.tif');
                            qqdataName=strcat(pathout,'Quantile-quantile','\',erase(app.imagelistListBox.Items(i),'.jpg'),'.mat');
                            qqdata.distribution(:,1)=h(1).XData';
                            qqdata.distribution(:,2)=h(1).YData';
                            qqdata.line(:,1)=h(3).XData';
                            qqdata.line(:,2)=h(3).YData';
                            save(qqdataName{1},'qqdata');
                            
                            %assignin('base','pp',qqName);
                            saveas(f,qqName{1});
                            close(f);
                            
                            clearvars qqdata;
                            
                            %=========绘制统计直方图并保存======================%
                            f=figure('Visible','off');
                            [N,~]=histcounts(allPitchPixel(:,1),fix(2*sqrt(length(allPitchPixel))));
                            N_count=sum(N);
                            
                            h=histfit(allPitchPixel(:,1),fix(2*sqrt(length(allPitchPixel))),'gev');
                                
                                h(1).YData=h(1).YData/N_count;
                                h(2).YData=h(2).YData/N_count;
                                h(1).FaceColor=[100 142 147]/255;
                                h(2).Color=[.8 .8 1];
                                f.WindowStyle='normal';
                                f.CurrentAxes.YLimMode='auto';
                            histName=strcat(pathout,'histogram','\',erase(app.imagelistListBox.Items(i),'.jpg'),'.tif');
                            dataName=strcat(pathout,'histogram','\',erase(app.imagelistListBox.Items(i),'.jpg'),'.mat');
                            
                            histdata.histData(:,1)=h(1).XData';
                            histdata.histData(:,2)=h(1).YData';
                            histdata.gevData(:,1)=h(2).XData';
                            histdata.gevData(:,2)=h(2).YData';
                            
                            
                            save(dataName{1},'histdata');
                            
                            
                            saveas(f,histName{1});
                            close(f);
                            
                            clearvars histdata;
                        catch
                            k(i)=0;m(i)=0;s(i)=0;
                            Pitch(i)=0;
                            Promience(i)=0;                     
                        end
                        
                            
                    end
                    %delete(p);                                  %关闭并行
                    save(strcat(pathout,'figPitch.mat'),'pitch2save');          %保存数据
            
                else
                    return
                end
                %=====================创建表并打表输出===================================%
                %文件名，螺距，标准差。三个位置参数，显著性程度
                varNames=app.UITable.ColumnName';
                DataTable=table(app.imagelistListBox.Items',Pitch',k',m',s',Promience','VariableNames',varNames);
                app.UITable.Data=DataTable;
                app.UITable.Visible='on';
                app.chartapp.GraphDisplyUIFigure.Visible='on';             %启动app1的显示
                
                
                %assignin('base','pp',app.colormethodDropDown);
                color=app.colormethodDropDown.Items(str2double(app.colormethodDropDown.Value));
                Ga=num2str(app.gradientSpinner.Value);
                Inter=app.interpolationDropDown.Value;
                Direc=num2str(app.directionMinSpinner.Value);
                
                
                TableName=strcat(pathout,'螺距数据_','颜色模式_',color,...
                    '_梯度阈值_',Ga,'_插值算法_',Inter,'_方向阈值_',Direc,'.csv');
                
                %assignin('base','pp',TableName{1});
                writetable(DataTable,TableName{1}); 


                %==========================计时结束,邮件通知===========================================%
                t1=toc(t0);
                t1=ceil(t1/60);
                
                mailtitle='当前螺距计算完成';
                mailcontent=strcat('计算成功结束,耗时',num2str(t1),'min,','结果文件(.CSV)已保存为：',TableName{1});
                %mailcontent='1111';
                
                mailme(app.MailEditField.Value,mailtitle,mailcontent);
                uialert(app.paramentcontrolUIFigure,strcat('计算耗时',num2str(t1),'min'),'计算结束','Icon','success');


            catch ME
                mailtitle='当前计算意外停止';
                mailcontent=strcat('错误信息：',ME.message);
                mailme(app.MailEditField.Value,mailtitle,mailcontent);
                uialert(app.paramentcontrolUIFigure,ME.message,'Error');
                
                
                return
            end


        end

        % Close request function: paramentcontrolUIFigure
        function paramentcontrolUIFigureCloseRequest(app, event)
            %%关闭app时弹窗请求
            msg=strcat('请确认是否关闭程序，是否继续?');
            selection=uiconfirm(app.paramentcontrolUIFigure,msg,'Confirm Operation');
            if selection=="OK"
                delete(app.chartapp)
                delete(app)
            else
                return
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.paramentcontrolUIFigure = uifigure('Visible', 'off');
            app.paramentcontrolUIFigure.Name = 'parament control';
            app.paramentcontrolUIFigure.CloseRequestFcn = createCallbackFcn(app, @paramentcontrolUIFigureCloseRequest, true);
            app.selectafolderButton = uibutton(app.paramentcontrolUIFigure, 'push');
            app.selectafolderButton.ButtonPushedFcn = createCallbackFcn(app, @selectafolderButtonPushed, true);
            app.selectafolderButton.Enable = 'off';
            app.selectafolderButton.Text = 'select a folder';
            app.imagelistListBoxLabel = uilabel(app.paramentcontrolUIFigure);
            app.imagelistListBoxLabel.Text = 'image list';
            app.imagelistListBox = uilistbox(app.paramentcontrolUIFigure);
            app.imagelistListBox.Items = {};
            app.imagelistListBox.ValueChangedFcn = createCallbackFcn(app, @imagelistListBoxValueChanged, true);
            app.imagelistListBox.Enable = 'off';
            app.imagelistListBox.Value = {};
            app.scalebarumEditFieldLabel = uilabel(app.paramentcontrolUIFigure);
            app.scalebarumEditFieldLabel.Text = 'scale bar(um)';
            app.scalebarumEditField = uieditfield(app.paramentcontrolUIFigure, 'numeric');
            app.scalebarumEditField.Limits = [0 Inf];
            app.scalebarumEditField.Value = 20;
            app.scalebarpixelEditFieldLabel = uilabel(app.paramentcontrolUIFigure);
            app.scalebarpixelEditFieldLabel.Text = 'scale bar(pixel)';
            app.scalebarpixelEditField = uieditfield(app.paramentcontrolUIFigure, 'numeric');
            app.scalebarpixelEditField.Limits = [0 Inf];
            app.scalebarpixelEditField.ValueDisplayFormat = '%.0f';
            app.scalebarpixelEditField.Value = 343;
            app.TabGroup = uitabgroup(app.paramentcontrolUIFigure);
            app.TabGroup.Visible = 'off';
            app.samplingTab = uitab(app.TabGroup);
            app.samplingTab.Title = 'sampling';
            app.blockNoLabel = uilabel(app.samplingTab);
            app.blockNoLabel.Text = 'block No.';
            app.blockNoEditField = uieditfield(app.samplingTab, 'numeric');
            app.blockNoEditField.Limits = [1 25];
            app.blockNoEditField.ValueDisplayFormat = '%.0f';
            app.blockNoEditField.ValueChangedFcn = createCallbackFcn(app, @gradientSpinnerValueChanged, true);
            app.blockNoEditField.Value = 13;
            app.lineNoEditFieldLabel = uilabel(app.samplingTab);
            app.lineNoEditFieldLabel.Text = 'line No.';
            app.lineNoEditField = uieditfield(app.samplingTab, 'numeric');
            app.lineNoEditField.Limits = [1 9];
            app.lineNoEditField.ValueDisplayFormat = '%.0f';
            app.lineNoEditField.ValueChangedFcn = createCallbackFcn(app, @gradientSpinnerValueChanged, true);
            app.lineNoEditField.Value = 5;
            app.controlTab = uitab(app.TabGroup);
            app.controlTab.Title = 'control';
            app.gradientSpinnerLabel = uilabel(app.controlTab);
            app.gradientSpinnerLabel.Text = 'gradient';
            app.gradientSpinner = uispinner(app.controlTab);
            app.gradientSpinner.Step = 0.1;
            app.gradientSpinner.Limits = [0 1];
            app.gradientSpinner.ValueDisplayFormat = '%.1f';
            app.gradientSpinner.ValueChangedFcn = createCallbackFcn(app, @gradientSpinnerValueChanged, true);
            app.gradientSpinner.Value = 0.7;
            app.interpolationDropDownLabel = uilabel(app.controlTab);
            app.interpolationDropDownLabel.Text = 'interpolation';
            app.interpolationDropDown = uidropdown(app.controlTab);
            app.interpolationDropDown.Items = {'cubic spline', 'piecewise-cubic Hermite', '', ''};
            app.interpolationDropDown.ItemsData = {'spline', 'pchip'};
            app.interpolationDropDown.ValueChangedFcn = createCallbackFcn(app, @gradientSpinnerValueChanged, true);
            app.interpolationDropDown.Value = 'pchip';
            app.directionMinSpinnerLabel = uilabel(app.controlTab);
            app.directionMinSpinnerLabel.Text = 'direction Min';
            app.directionMinSpinner = uispinner(app.controlTab);
            app.directionMinSpinner.Step = 0.02;
            app.directionMinSpinner.Limits = [0 Inf];
            app.directionMinSpinner.ValueDisplayFormat = '%.2f';
            app.directionMinSpinner.Value = 2.9;
            app.batchTab = uitab(app.TabGroup);
            app.batchTab.Title = 'batch';
            app.batchprocessingButton = uibutton(app.batchTab, 'push');
            app.batchprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @batchprocessingButtonPushed, true);
            app.batchprocessingButton.Text = 'batch processing';
            app.MailEditFieldLabel = uilabel(app.batchTab);
            app.MailEditFieldLabel.Text = 'Mail';
            app.MailEditField = uieditfield(app.batchTab, 'text');
            app.MailEditField.Tooltip = {'请尽量避免使用qq邮箱，易造成自动邮箱封号。'};
            app.MailEditField.Value = 'qiaozhenyu12580@163.com';
            app.colormethodDropDown_2Label = uilabel(app.paramentcontrolUIFigure);
            app.colormethodDropDown_2Label.Text = 'color method';
            app.colormethodDropDown = uidropdown(app.paramentcontrolUIFigure);
            app.colormethodDropDown.Items = {'R', 'G', 'B', 'Gray'};
            app.colormethodDropDown.ItemsData = {'1', '2', '3', '4'};
            app.colormethodDropDown.ValueChangedFcn = createCallbackFcn(app, @colormethodDropDownValueChanged, true);
            app.colormethodDropDown.Enable = 'off';
            app.colormethodDropDown.Value = '2';
            app.UITable = uitable(app.paramentcontrolUIFigure);
            app.UITable.ColumnName = {'FileName'; 'Pitch'; 'k'; 'm'; 's'; 'Promience'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.paramentcontrolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.paramentcontrolUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.paramentcontrolUIFigure)
        end
    end
end